classdef controlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        StartStopButton             matlab.ui.control.StateButton
        ThrottleSetEditFieldLabel   matlab.ui.control.Label
        ThrottleSetEditField        matlab.ui.control.NumericEditField
        CurrthrottleEditFieldLabel  matlab.ui.control.Label
        CurrthrottleEditField       matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        TimestampLabel              matlab.ui.control.Label
        Received_timestampLabel     matlab.ui.control.Label
        SpeedLabel                  matlab.ui.control.Label
        received_speedLabel         matlab.ui.control.Label
        ThrottleSliderLabel         matlab.ui.control.Label
        ThrottleSlider              matlab.ui.control.Slider
        ConnectionsPanel            matlab.ui.container.Panel
        SendArduinoPanel            matlab.ui.container.Panel
        PortDropDownLabel           matlab.ui.control.Label
        PortDropDown                matlab.ui.control.DropDown
        ConnectButton               matlab.ui.control.StateButton
        ReceiveArduinoPanel         matlab.ui.container.Panel
        PortDropDown_2Label         matlab.ui.control.Label
        PortDropDown_2              matlab.ui.control.DropDown
        ConnectButton_2             matlab.ui.control.StateButton
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        ClearConnectionsButton      matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        myTimer = timer % Description
        arduinoSerial = serial('COM1');
        arduinoSerial_Send = serial('COM1');% Description
        arduinoSerial_Receive = serial('COM1');
        receivedData = []% Description
    end
    
    properties (Access = private)
        Property4 % Description
        setThrottleValue = 0 % Description
        myLine % Description
        numOfBytes = 8;  % NUM OF BYTES 
        bufferLength = 30;  % 1 buffer length = 1 data Set  % läuft :0  mit 40 ok
        lengthCalcSpeed = 10;
        bufferBytes;
        temp = 0;
        temp2 = 0;
        currentPos = 0;
        varTimestamp = 0;
        
        %Array to save Data
        %receivedArray = zeros(1000000,8);  % NUM OF BYTES !
        currentPositionInRecArr = 2;
        myNumber = 0;
        
        %Array to receive
        received = zeros(20,8);
        receivedDataset = 0;


        receivedArrayConverted = [];
        displayLength = 1000;
        time = [];
        speed = 0;
        
        %  Timestamp  | Signal Input | Speed  [RPM] |  force  |  current
        savedData = zeros(10000000,5);
        posCounter = 2;
        baud = 115200;


    end
    
    methods (Access = public)
        
        function TimerCallback(app)
            
            app.Label.Text = datestr(now);
            
         
            myPlot(app);
            %sendThrottle(app);
        
            
        end


        function sendThrottle(app)
            % if app.arduinoSerial.BytesToOutput == 0
            calc = app.setThrottleValue*2.55;
            if calc > 255
                calc = 255;
            end
            fwrite(app.arduinoSerial_Send,calc,'async');
            
            % end
        end


        function readData(app)
            
            
            % read and scale data from serial port
            app.received = fread(app.arduinoSerial_Receive, app.bufferBytes, 'uint8')';
            
            for cn = 1:app.bufferLength
                app.myNumber = cn*app.numOfBytes;
                % app.receivedArray(cn,:) = received(1,(myNumber-app.numOfBytes+1):myNumber);
                app.currentPos = app.currentPositionInRecArr+cn-1;
                
                %oneDataset
                app.receivedData((app.currentPos),:) = app.received(1,(app.myNumber-app.numOfBytes+1):app.myNumber);
                
               
                %Save Timestamp
                app.savedData((app.currentPos),1) = typecast(uint8(app.receivedData(app.currentPos,1:4)), 'uint32');
                
                
                %Save current throttle
                app.savedData((app.currentPos),2) = app.setThrottleValue;
                
                %Save/Calculate Speed
                app.savedData((app.currentPos),3) = 60000000/(app.savedData((app.currentPos),1)-app.savedData((app.currentPos-1),1));
                
                %Save weight
                app.savedData((app.currentPos),4) = (typecast(uint8(app.receivedData(app.currentPos,5:6)), 'uint16'))/10;
              
                 %Save current
                app.savedData((app.currentPos),5) = typecast(uint8(app.receivedData(app.currentPos,7:8)), 'uint16');
              
               
            end
           
            
             %New Position in Array
             app.currentPositionInRecArr =  app.currentPositionInRecArr + app.bufferLength;


            %Counter
            app.posCounter = app.posCounter +1;
            
        end
        
        function plotThrottle(app)
             myfig = figure;
            title('Stellgröße');
            plot(gca,app.savedData(1:app.currentPositionInRecArr,1),app.savedData(1:app.currentPositionInRecArr,2));
            
            myfig = figure;
            title('Drehzahl');
            plot(gca,app.savedData(1:app.currentPositionInRecArr,1),app.savedData(1:app.currentPositionInRecArr,3));
            % plot(myfig,app.savedData(:,2))
            
            myfig = figure;
            title('Schub');
            plot(gca,app.savedData(1:app.currentPositionInRecArr,1),app.savedData(1:app.currentPositionInRecArr,4));
            
            
            myfig = figure;
            title('Strom');
            plot(gca,app.savedData(1:app.currentPositionInRecArr,1),app.savedData(1:app.currentPositionInRecArr,5));
            
            
        end
        
        
        %Final conversion for the measurement
        function convertMeasurement(app)


        end
        
    end
    
    methods (Access = private)
        
        function setThrottle(app,newThrottleValue)
            app.setThrottleValue = newThrottleValue;
            app.ThrottleSlider.Value = newThrottleValue;
            app.ThrottleSetEditField.Value = newThrottleValue;
            sendThrottle(app);
            
        end
        
        function myPlot(app)
            if isempty(app.receivedData) == 0
                if app.currentPositionInRecArr > app.displayLength
                    %                     plot(app.UIAxes,app.receivedData((end-100):end,1));
                    
                    %set(app.myLine,'YData',app.receivedData((end-app.displayLength):end,1));
                    set(app.myLine,'YData',app.savedData((app.currentPositionInRecArr-app.displayLength):app.currentPositionInRecArr,3));
                else
                    app.myLine = plot(app.UIAxes,app.savedData(1:app.currentPositionInRecArr,3));
                    %app.myLine = plot(app.UIAxes,app.time);


                end
            end
        end
        
        function setStandBy(app)
            stop(app.myTimer);
            
            
             newArray = app.savedData(1:app.currentPositionInRecArr-1,:);
             
%               begin = app.savedData(2,1);
              
              
             newArray(:,1) = newArray(:,1)/1000000;
         
             
            assignin('base','recordData',newArray);


            closeSendPort(app);
            closeReceivePort(app);
            instrreset
            
            checkAvailableConnections(app);
            
            %             delete(app.myTimer);
            
            
        end


        function setRecord(app)
            
            
            %Start Timer
            start(app.myTimer);
            
            fwrite(app.arduinoSerial_Receive,1,'uint8','async');


        end
        
        function checkIfAllConnected(app)
            if app.ConnectButton.Value == 1 && app.ConnectButton_2.Value == 1
                app.StartStopButton.Enable = 'on';
            else
                app.StartStopButton.Enable = 'off';
            end
        end
        
        function connectSerialReceive(app)


            %define serial
            app.arduinoSerial_Receive = serial(app.PortDropDown_2.Value,'BaudRate',app.baud);


            % define call funcion
            app.arduinoSerial_Receive.BytesAvailableFcnCount = app.bufferBytes;
            app.arduinoSerial_Receive.BytesAvailableFcnMode = 'byte';
            app.arduinoSerial_Receive.BytesAvailableFcn = @(~, ~)app.readData;


            % open port
            fopen(app.arduinoSerial_Receive);


            %check if port is opened
            if strcmp(convertCharsToStrings(app.arduinoSerial_Receive.Status), 'open')
                app.Lamp_3.Color = 'green';
                
                
            else
                app.Lamp_3.Color = 'red';
                app.ConnectButton_2.Value = 0;
            end
            checkIfAllConnected(app);
        end
        
        
        function connectSerialSend(app)
            %define serial
            app.arduinoSerial_Send = serial(app.PortDropDown.Value,'BaudRate',app.baud);
            
            % open port
            fopen(app.arduinoSerial_Send);
            
            %check if port is opened
            if strcmp(convertCharsToStrings(app.arduinoSerial_Send.Status), 'open')
                app.Lamp_2.Color = 'green';
                
            else
                app.Lamp_2.Color = 'red';
                app.ConnectButton.Value = 0;
            end
            checkIfAllConnected(app);
        end
        
        function checkAvailableConnections(app)
            p = instrhwinfo('serial');
            app.PortDropDown.Items = p.AvailableSerialPorts;
            app.PortDropDown_2.Items = p.AvailableSerialPorts;
        end
        
        function closeSendPort(app)
            if ~isempty(app.arduinoSerial_Send)
                stopasync(app.arduinoSerial_Send);
                fclose(app.arduinoSerial_Send);
            end
            
            app.Lamp_2.Color = 'red';
            app.ConnectButton.Value = 0;
            checkIfAllConnected(app);
        end
        
        function closeReceivePort(app)
            
            stopasync(app.arduinoSerial_Receive);
            fclose(app.arduinoSerial_Receive);
            checkIfAllConnected(app);
            app.Lamp_3.Color = 'red';
            app.ConnectButton_2.Value = 0;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function SetupSystem(app)
            %Calc Buffer length
            app.bufferBytes = app.numOfBytes*app.bufferLength;
        
            %Timer setup
            app.myTimer = timer('StartDelay', 0, 'Period', 0.1,'ExecutionMode', 'fixedRate','BusyMode','drop');
            app.myTimer.TimerFcn = @(~, ~) app.TimerCallback;
            
            %Check avalable Serial Ports and display
            checkAvailableConnections(app);
            
            %Satus not ready
            app.StartStopButton.Enable = 'off';
            
            
        end

        % Callback function
        function InputSliderValueChanged(app, event)
            value = app.InputSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: ThrottleSlider
        function ThrottleKnobValueChanging(app, event)
            changingValue = event.Value;
            
            setThrottle(app, changingValue);
            %             fwrite(arduino,changingValue);
            
            
        end

        % Value changed function: ThrottleSetEditField
        function ThrottleSetEditFieldValueChanged(app, event)
            value = app.ThrottleSetEditField.Value;
            setThrottle(app, value)
            %             fwrite(arduino,changingValue);
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;
            
            
            if value == 1
                %Running /Start
                
                
                %Receive Data
                setRecord(app);
                
                app.Lamp.Color = 'green';
                
            else
                %Stop
                
                setStandBy(app);
                
                
                app.Lamp.Color = 'red';


            end
            
            
        end

        % Close request function: UIFigure
        function CloseSystem(app, event)
            
            
            %             Serial_Close
            %             pause(0.01);
            
            %             fclose(app.arduinoSerial);
            %             delete(app.arduinoSerial)
            
            %setStandBy(app);
            pause(1);
            delete(app.myTimer);
            %
            %             pause(0.01);
            delete(app)


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotThrottle(app);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            
            if value == 1
                connectSerialSend(app);
            else
                closeSendPort(app);
                
                
            end
        end

        % Value changed function: ConnectButton_2
        function ConnectButton_2ValueChanged(app, event)
            value = app.ConnectButton_2.Value;
            
            if value == 1
                connectSerialReceive(app);
            else
                closeReceivePort(app);
                
            end
        end

        % Button pushed function: ClearConnectionsButton
        function ClearConnectionsButtonPushed(app, event)
            closeSendPort(app);
            closeReceivePort(app);
            instrreset
            
            checkAvailableConnections(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseSystem, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Throttle')
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.ThrottleSetEditFieldLabel = uilabel(app.UIFigure);
            app.ThrottleSetEditFieldLabel.Text = ' Throttle Set:';
            app.ThrottleSetEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThrottleSetEditField.Limits = [0 100];
            app.ThrottleSetEditField.ValueDisplayFormat = '%.2f';
            app.ThrottleSetEditField.ValueChangedFcn = createCallbackFcn(app, @ThrottleSetEditFieldValueChanged, true);
            app.CurrthrottleEditFieldLabel = uilabel(app.UIFigure);
            app.CurrthrottleEditFieldLabel.Text = 'Curr. throttle';
            app.CurrthrottleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrthrottleEditField.Limits = [0 100];
            app.CurrthrottleEditField.ValueDisplayFormat = '%.2f';
            app.CurrthrottleEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TimestampLabel = uilabel(app.UIFigure);
            app.TimestampLabel.Text = 'Timestamp:';
            app.Received_timestampLabel = uilabel(app.UIFigure);
            app.Received_timestampLabel.Text = 'Received_timestamp';
            app.SpeedLabel = uilabel(app.UIFigure);
            app.SpeedLabel.Text = 'Speed:';
            app.received_speedLabel = uilabel(app.UIFigure);
            app.received_speedLabel.Text = 'received_speed';
            app.ThrottleSliderLabel = uilabel(app.UIFigure);
            app.ThrottleSliderLabel.Text = 'Throttle';
            app.ThrottleSlider = uislider(app.UIFigure);
            app.ThrottleSlider.ValueChangedFcn = createCallbackFcn(app, @ThrottleKnobValueChanging, true);
            app.ConnectionsPanel = uipanel(app.UIFigure);
            app.ConnectionsPanel.Title = 'Connections';
            app.SendArduinoPanel = uipanel(app.ConnectionsPanel);
            app.SendArduinoPanel.Title = 'Send Arduino';
            app.PortDropDownLabel = uilabel(app.SendArduinoPanel);
            app.PortDropDownLabel.Text = ' Port';
            app.PortDropDown = uidropdown(app.SendArduinoPanel);
            app.ConnectButton = uibutton(app.SendArduinoPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ReceiveArduinoPanel = uipanel(app.ConnectionsPanel);
            app.ReceiveArduinoPanel.Title = 'Receive Arduino';
            app.PortDropDown_2Label = uilabel(app.ReceiveArduinoPanel);
            app.PortDropDown_2Label.Text = ' Port';
            app.PortDropDown_2 = uidropdown(app.ReceiveArduinoPanel);
            app.ConnectButton_2 = uibutton(app.ReceiveArduinoPanel, 'state');
            app.ConnectButton_2.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_2ValueChanged, true);
            app.ConnectButton_2.Text = 'Connect';
            app.Lamp_3 = uilamp(app.ConnectionsPanel);
            app.Lamp_2 = uilamp(app.ConnectionsPanel);
            app.ClearConnectionsButton = uibutton(app.ConnectionsPanel, 'push');
            app.ClearConnectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConnectionsButtonPushed, true);
            app.ClearConnectionsButton.Text = 'Clear Connections';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = controlApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @SetupSystem)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end